classdef cbir_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SimilarimagesLabel            matlab.ui.control.Label
        Image10                       matlab.ui.control.Image
        Image9                        matlab.ui.control.Image
        Image8                        matlab.ui.control.Image
        Image7                        matlab.ui.control.Image
        Image6                        matlab.ui.control.Image
        Image5                        matlab.ui.control.Image
        Image4                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        RightPanel                    matlab.ui.container.Panel
        QueryImageLabel               matlab.ui.control.Label
        queryImage                    matlab.ui.control.Image
        queryimagepathEditField       matlab.ui.control.EditField
        queryimagepathEditFieldLabel  matlab.ui.control.Label
        SearchButton                  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % create variables to store our data object from model training
        lshStruct % lshObject
        featureSet % set of features
        ref % image reference
        trainingSet 
        testingSet
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % make call to train model on app launch
            % assign return values to app level variables
            [app.lshStruct, app.featureSet, app.ref, app.trainingSet, app.testingSet] = constructLSH();
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            % set to no image till image is loaded
            app.QueryImageLabel.Text = "No image";
            app.QueryImageLabel.BackgroundColor = "red";
            imagePath = app.queryimagepathEditField.Value;
            % display query image to user
            I = imread(imagePath);
            displayImage = I;
            if size(displayImage,3)~= 3
                displayImage = repmat(displayImage, 1, 1, 3);
            end
            app.queryImage.ImageSource = displayImage;
            app.QueryImageLabel.Text = imagePath;

            % query the lsh table
            [ranks] = searchLSH(imagePath, app.featureSet, app.ref, app.lshStruct);    

            imageArray = {};
            counter = 1;
            %%%%%%%%%%%%%%%%%%%%%%%%
            for pic = 1 : min(20, length(ranks))
                if ranks(pic,2)>0
                    % read valid images into the array
                    I = readimage(app.trainingSet, ranks(pic, 2));
                    imageArray{counter} = I;
                    counter = counter + 1;
                end
            end

            % output the top 10 similar images in to the views
            app.Image.ImageSource = imageArray{1};
            app.Image2.ImageSource = imageArray{2};
            app.Image3.ImageSource = imageArray{3};
            app.Image4.ImageSource = imageArray{4};
            app.Image5.ImageSource = imageArray{5};
            app.Image6.ImageSource = imageArray{6};
            app.Image7.ImageSource = imageArray{7};
            app.Image8.ImageSource = imageArray{8};
            app.Image9.ImageSource = imageArray{9};
            app.Image10.ImageSource = imageArray{10};
            app.QueryImageLabel.BackgroundColor = "green";
            %%%%%%%%%%%%%%%%%%%%%%%
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {605, 605};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {379, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image2 = uiimage(app.LeftPanel);
            app.Image3 = uiimage(app.LeftPanel);
            app.Image4 = uiimage(app.LeftPanel);
            app.Image5 = uiimage(app.LeftPanel);
            app.Image6 = uiimage(app.LeftPanel);
            app.Image7 = uiimage(app.LeftPanel);
            app.Image8 = uiimage(app.LeftPanel);
            app.Image9 = uiimage(app.LeftPanel);
            app.Image10 = uiimage(app.LeftPanel);
            app.SimilarimagesLabel = uilabel(app.LeftPanel);
            app.SimilarimagesLabel.Text = 'Similar images';
            app.RightPanel = uipanel(app.GridLayout);
            app.SearchButton = uibutton(app.RightPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.queryimagepathEditFieldLabel = uilabel(app.RightPanel);
            app.queryimagepathEditFieldLabel.Text = 'query image path';
            app.queryimagepathEditField = uieditfield(app.RightPanel, 'text');
            app.queryImage = uiimage(app.RightPanel);
            app.QueryImageLabel = uilabel(app.RightPanel);
            app.QueryImageLabel.Text = 'Query Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cbir_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end